classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DropDown_2                   matlab.ui.control.DropDown
        DropDown_2Label              matlab.ui.control.Label
        EditField_2                  matlab.ui.control.EditField
        DropDown                     matlab.ui.control.DropDown
        DropDownLabel                matlab.ui.control.Label
        LossFunctionForIterationsEditField  matlab.ui.control.EditField
        LossFunctionForIterationsEditFieldLabel  matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        NumberofParticlesEditField   matlab.ui.control.NumericEditField
        NumberofParticlesEditFieldLabel  matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        NumberofIterationsEditField  matlab.ui.control.NumericEditField
        NumberofIterationsEditFieldLabel  matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function MainFunc(app,event)
            global I;
            global NumOfParticles;
            global NumOfIterations;
            global str;
            global img;

            imgDir = strcat('Images/',img);
            I = imread(imgDir);
            imshow(I,'Parent',app.UIAxes_2)
            title(app.UIAxes_2,'Selected Image')
            
            nVar=6;            % Number of Decision Variables
            
            VarSize=[1 nVar];   % Size of Decision Variables Matrix
            
            VarMin= 0;         % Lower Bound of Variables
            VarMax= 256;         % Upper Bound of Variables
           
            VarMinWp = 20;
            VarMaxWp = size(I,2);
            
            VarMinHp = 20;
            VarMaxHp = size(I,1);
            
            VarMinWHt = 3;
            VarMaxWHt = 15;
            
            %% PSO Parameters
            
            MaxIt=NumOfIterations;      % Maximum Number of Iterations
            
            nPop=NumOfParticles;        % Population Size (Swarm Size)
            
            % PSO Parameters
            w=1;            % Inertia Weight
            wdamp=0.99;     % Inertia Weight Damping Ratio
            c1=1.5;         % Personal Learning Coefficient
            c2=2.0;         % Global Learning Coefficient
            
            % Velocity Limits
            VelMax=0.5*(VarMax-VarMin);
            VelMin=-VelMax;
            
            %% Initialization
            
            empty_particle.Position=[];
            empty_particle.Cost=[];
            empty_particle.Velocity=[];
            empty_particle.Best.Position=[];
            empty_particle.Best.Cost=[];
            
            particle=repmat(empty_particle,nPop,1);
            
            GlobalBest.Cost=inf;
            % [~,tempI] = maxk(sortedImage,nPop);
            % initXs = floor(tempI/200);
            % initYs = tempI-floor(tempI/200)*200;
            
            
            for i=1:nPop    
                % Initialize Position
            %     tempX = initXs(i)+randi([VarMinX VarMaxX]);
            %     tempY = initYs(i)+randi([VarMinY VarMaxY]);
            
                particle(i).Position(1) = randi([VarMinWp,VarMaxWp]);
                particle(i).Position(2) = randi([VarMinHp,VarMaxHp]);
                particle(i).Position(3) = randi([VarMinWp,floor(VarMaxWp/4)]);
                particle(i).Position(4) = randi([VarMinHp,floor(VarMaxHp/4)]);
                particle(i).Position(5) = randi([VarMinWHt,VarMaxWHt]);
                particle(i).Position(6) = randi([VarMinWHt,VarMaxWHt]);
                % Initialize Velocity
                particle(i).Velocity=zeros(VarSize);
                
                % Evaluation
                particle(i).Cost=CostFunction(I,particle(i).Position,0);
                
                % Update Personal Best
                particle(i).Best.Position=particle(i).Position;
                particle(i).Best.Cost=particle(i).Cost;
                
                % Update Global Best
                if particle(i).Best.Cost<GlobalBest.Cost
                    
                    GlobalBest=particle(i).Best;
                    
                end
                
            end
            
            BestCost=zeros(MaxIt,1);
            
            %% PSO Main Loop
            
            for it=1:MaxIt
                
                for i=1:nPop
                    
                    % Update Velocity
                    particle(i).Velocity = w*particle(i).Velocity ...
                        +c1*rand(VarSize).*(particle(i).Best.Position-particle(i).Position) ...
                        +c2*rand(VarSize).*(GlobalBest.Position-particle(i).Position);
                    
                    % Apply Velocity Limits
                    particle(i).Velocity = max(particle(i).Velocity,VelMin);
                    particle(i).Velocity = min(particle(i).Velocity,VelMax);
                    
                    % Update Position
                    particle(i).Position = round(particle(i).Position + particle(i).Velocity);
                    
                    % Velocity Mirror Effect
                    IsOutside=[(particle(i).Position(1)<VarMin | particle(i).Position(1)>VarMax)...
                        (particle(i).Position(2)<VarMin | particle(i).Position(2)>VarMax)...
                        (particle(i).Position(3)<VarMinWp | particle(i).Position(3)>VarMaxWp)...
                        (particle(i).Position(4)<VarMinHp | particle(i).Position(4)>VarMaxHp)...
                        (particle(i).Position(5)<VarMinWHt | particle(i).Position(5)>VarMaxWHt)...
                        (particle(i).Position(6)<VarMinWHt | particle(i).Position(6)>VarMaxWHt)];
                    particle(i).Velocity(IsOutside)=-particle(i).Velocity(IsOutside);
                    
                    % Apply Position Limits
                    particle(i).Position(1) = max(particle(i).Position(1),1);
                    particle(i).Position(1) = min(particle(i).Position(1),VarMaxWp);
                    
                    particle(i).Position(2) = max(particle(i).Position(2),1);
                    particle(i).Position(2) = min(particle(i).Position(2),VarMaxHp);
            
                    particle(i).Position(3) = max(particle(i).Position(3),VarMinWp);
                    particle(i).Position(3) = min(particle(i).Position(3),VarMaxWp/4);
            
                    particle(i).Position(4) = max(particle(i).Position(4),VarMinHp);
                    particle(i).Position(4) = min(particle(i).Position(4),VarMaxHp/4);
            
                    particle(i).Position(5) = max(particle(i).Position(5),VarMinWHt);
                    particle(i).Position(5) = min(particle(i).Position(5),VarMaxWHt);
            
                    particle(i).Position(6) = max(particle(i).Position(6),VarMinWHt);
                    particle(i).Position(6) = min(particle(i).Position(6),VarMaxWHt);
            
                    if(particle(i).Position(1)+particle(i).Position(3)>VarMaxWp ...
                            &&particle(i).Position(2)+particle(i).Position(4)>VarMaxHp)
                        particle(i).Position(1) = VarMaxWp-particle(i).Position(3)-1;
                        particle(i).Position(2) = VarMaxHp-particle(i).Position(4)-1;
                    elseif(particle(i).Position(1)+particle(i).Position(3)>VarMaxWp)
                        particle(i).Position(1) = VarMaxWp-particle(i).Position(3)-1;
                    elseif(particle(i).Position(2)+particle(i).Position(4)>VarMaxHp)
                        particle(i).Position(2) = VarMaxHp-particle(i).Position(4)-1;
                    end
                    
                    % Evaluation
                    particle(i).Cost = CostFunction(I,particle(i).Position,0);
                    
                    % Update Personal Best
                    if particle(i).Cost<particle(i).Best.Cost
                        
                        particle(i).Best.Position=particle(i).Position;
                        particle(i).Best.Cost=particle(i).Cost;
                        
                        % Update Global Best
                        if particle(i).Best.Cost<GlobalBest.Cost
                            GlobalBest=particle(i).Best;
                            plotFunction(app,I,GlobalBest);
                        end
                        
                    end
                    
                end
                
                BestCost(it)=GlobalBest.Cost;
                str = ['Iteration ' num2str(it) ': Best Cost = ' num2str(BestCost(it))];
                plotLoss(app,BestCost);
                LossFunctionForIterationsEditFieldValueChanging(app,event)
                
                w=w*wdamp;
                
            end
            
            BestSol = GlobalBest;
            
            % for i=1:100
            %     Temp = CostFunction(I,particle(i).Position,1);
            % end
            
            Fitness = CostFunction(I,GlobalBest.Position,0);

            plotFunction(app,I,GlobalBest);

            %% Results

        end
        
        function plotFunction(app,I,GlobalBest)
            particleImage = imcrop(I,[GlobalBest.Position(1) GlobalBest.Position(2) GlobalBest.Position(3) GlobalBest.Position(4)]);

            maximum = max(max(particleImage));
            [targetCy,targetCx]=find(particleImage==maximum);
        
            try
                targetCx = GlobalBest.Position(1) + targetCx(1);
                targetCy = GlobalBest.Position(2) + targetCy(1);
            catch
                disp('you did something wrong')
            end
        
            try
                targetImage = imcrop(I,[targetCx-GlobalBest.Position(5)/2 targetCy-GlobalBest.Position(6)/2 GlobalBest.Position(5) GlobalBest.Position(6)]);
            catch
                disp('you did something wrong')
            end


            imshow(I,'Parent',app.UIAxes)
            drawrectangle(app.UIAxes,'Position',[targetCx-GlobalBest.Position(5)/2 ...
                targetCy-GlobalBest.Position(6)/2 GlobalBest.Position(5) GlobalBest.Position(6)], ...
                'StripeColor','r','Label','Target Bounding Box');
%             drawrectangle(app.UIAxes,'Position',[GlobalBest.Position(1),GlobalBest.Position(2), ...
%                 GlobalBest.Position(3),GlobalBest.Position(4)],'StripeColor','b' ...
%                 ,'Label','Search Bounding Box');
            title(app.UIAxes,'Output of the DSPD')
%             legend(app.UIAxes,"Target Bounding Box","Search Bounding Box")
            drawnow
        end
        
        function plotLoss(app,BestCost)
            plot(app.UIAxes_3,BestCost,'LineWidth',2);
            semilogy(app.UIAxes_3,BestCost,'LineWidth',2);
            xlabel(app.UIAxes_3,'Iteration');
            ylabel(app.UIAxes_3,'Best Cost');
            title(app.UIAxes_3,'Loss vs Iterations Plot')
            legend(app.UIAxes_3,'Loss Value')
            grid(app.UIAxes_3,'on')
            drawnow
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            NumberofParticlesEditFieldValueChanged(app,event)
            NumberofIterationsEditFieldValueChanged(app, event)
            MainFunc(app,event)
        end

        % Value changed function: NumberofParticlesEditField
        function NumberofParticlesEditFieldValueChanged(app, event)
            global NumOfParticles;
            NumOfParticles = app.NumberofParticlesEditField.Value;
        end

        % Value changed function: NumberofIterationsEditField
        function NumberofIterationsEditFieldValueChanged(app, event)
            global NumOfIterations;
            NumOfIterations = app.NumberofIterationsEditField.Value;
        end

        % Value changing function: LossFunctionForIterationsEditField
        function LossFunctionForIterationsEditFieldValueChanging(app, event)
            global str;
            app.LossFunctionForIterationsEditField.Value = str;
            drawnow
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            global img;
            MyFolderInfo = dir('Images');

            for i = 3:size(MyFolderInfo,1)
                imageNames(1,i-2) = convertCharsToStrings(MyFolderInfo(i).name);
            end
            app.DropDown.Items = imageNames;
            img = app.DropDown.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Found Target')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Selected Image')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Loss Curve')
            xlabel(app.UIAxes_3, 'Iterations')
            ylabel(app.UIAxes_3, 'Loss Value')
            zlabel(app.UIAxes_3, 'Z')
            app.NumberofIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationsEditFieldLabel.Text = 'Number of Iterations';
            app.NumberofIterationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofIterationsEditFieldValueChanged, true);
            app.NumberofIterationsEditField.Value = 10;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumberofParticlesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofParticlesEditFieldLabel.Text = 'Number of Particles';
            app.NumberofParticlesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofParticlesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofParticlesEditFieldValueChanged, true);
            app.NumberofParticlesEditField.Value = 200;
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'EE583 Optimization Final Project - Onur Selim KILIÇ';
            app.LossFunctionForIterationsEditFieldLabel = uilabel(app.UIFigure);
            app.LossFunctionForIterationsEditFieldLabel.Text = 'Loss Function For Iterations';
            app.LossFunctionForIterationsEditField = uieditfield(app.UIFigure, 'text');
            app.LossFunctionForIterationsEditField.ValueChangingFcn = createCallbackFcn(app, @LossFunctionForIterationsEditFieldValueChanging, true);
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Images', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Images';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'First select any of the images from the drop down menu. This will activate the drop down button. Then reselect another image from the drop down menu.';
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = 'Drop Down';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'Calculate Gradients Numerically', 'Calculate Gradients Analytically'};
            app.DropDown_2.Value = 'Calculate Gradients Numerically';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end